classdef gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        q0SpinnerLabel                 matlab.ui.control.Label
        q0Spinner                      matlab.ui.control.Spinner
        q1SpinnerLabel                 matlab.ui.control.Label
        q1Spinner                      matlab.ui.control.Spinner
        q2SpinnerLabel                 matlab.ui.control.Label
        q2Spinner                      matlab.ui.control.Spinner
        q3SpinnerLabel                 matlab.ui.control.Label
        q3Spinner                      matlab.ui.control.Spinner
        q4SpinnerLabel                 matlab.ui.control.Label
        q4Spinner                      matlab.ui.control.Spinner
        q5SpinnerLabel                 matlab.ui.control.Label
        q5Spinner                      matlab.ui.control.Spinner
        q6SpinnerLabel                 matlab.ui.control.Label
        q6Spinner                      matlab.ui.control.Spinner
        CommittoOrderButton            matlab.ui.control.Button
        TeachmodeoveridesjointanglesLabel  matlab.ui.control.Label
        XSpinnerLabel                  matlab.ui.control.Label
        XSpinner                       matlab.ui.control.Spinner
        YSpinnerLabel                  matlab.ui.control.Label
        YSpinner                       matlab.ui.control.Spinner
        ZSpinnerLabel                  matlab.ui.control.Label
        ZSpinner                       matlab.ui.control.Spinner
        ManualEndeffectorControlLabel  matlab.ui.control.Label
        MoveItButton                   matlab.ui.control.Button
        EStopButton                    matlab.ui.control.Button
        IngredientsListLabel           matlab.ui.control.Label
        BowlButton                     matlab.ui.control.Button
        MushroomButton                 matlab.ui.control.Button
        TomatoButton                   matlab.ui.control.Button
        CarrotButton                   matlab.ui.control.Button
        LettuceButton                  matlab.ui.control.Button
        OnionButton                    matlab.ui.control.Button
        TomatoSauceButton              matlab.ui.control.Button
        MakeMySaladButton              matlab.ui.control.Button
    end

    properties (Access = public)
        motion;
        sawyer1;
        table;
        bowl;
        knife;
        mushroom;
        tomato;
        carrot;
        lettuce;
        onion;
        tomatoSauce;
        workSpace;
        floorOffset             = (-1.0896/2);                                                  % measured height or table in body0.ply
        objectOffset            = transl(0, 0 , -0.1);                                         % offset to lower endeffector onto object
        sawyerBase              = transl(0,0,0);
        sawywerInit             = transl(0.2, 0, 1.2);
        bowlBaseCoord           = transl(-0.4, 0.5, 0);
        knifeCoord              = transl(0, -0.5, 0.42) * trotx(deg2rad(0)) * troty(deg2rad(90));
        mushroomBaseCoord       = transl(0.6, -0.4, 0.0);
        tomatoBaseCoord         = transl(0.6, 0.0, 0.0);
        carrotBaseCoord         = transl(0.4, -0.2, 0.0);
        lettuceBaseCoord        = transl(0.4, 0.5, 0.0);
        onionBaseCoord          = transl(0.0, 0.6, 0.0);
        tomatoSauceBaseCoord    = transl(-0.2, 0.5 ,0.0);
        
    end
    methods (Access = public)
        
        function results = init(app)
      
            pause(0.01);
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.workSpace = [-2.5 2.5 -2.5 2.5 (2*app.floorOffset) 2];
            axis(app.workSpace);
            hold on;
            app.table = body(app.workSpace, 'table', transl(0,0,app.floorOffset), app.floorOffset);            % Dimensions of the table (x, y, z) = (1.4880, 2.3383, 1.0896)
            app.knife = body(app.workSpace, 'knife', app.knifeCoord, app.floorOffset);
            app.sawyer1 = sawyer(app.workSpace, 1, app.sawyerBase);
            app.sawyer1.lightCurtain();
        end

        % Value changed function: q0Spinner
        function q0SpinnerValueChanged(app, event)
            value = app.q0Spinner.Value;
            app.sawyer1.currentJoints(1) = deg2rad(value);
            app.sawyer1.model.plot3d(app.sawyer1.currentJoints,'workspace',app.sawyer1.workspace,'floorlevel', 0);
            pause(0.001);
        end

        % Button pushed function: CommittoOrderButton
        function CommittoOrderButtonPushed(app, event)
            % Init Sawyer
            bodies          = [app.table, app.bowl, app.knife, app.mushroom, app.tomato, app.carrot, app.lettuce, app.onion, app.tomatoSauce];
            app.motion      = move(app.sawyer1, bodies);
            disp('Order Confirmed!');
        end

        % Value changed function: q1Spinner
        function q1SpinnerValueChanged(app, event)
            value = app.q1Spinner.Value;
            app.sawyer1.currentJoints(2) = deg2rad(value);
            app.sawyer1.model.plot3d(app.sawyer1.currentJoints,'workspace',app.sawyer1.workspace,'floorlevel', 0);
            pause(0.001);
        end

        % Value changed function: q2Spinner
        function q2SpinnerValueChanged(app, event)
            value = app.q2Spinner.Value;
            app.sawyer1.currentJoints(3) = deg2rad(value);
            app.sawyer1.model.plot3d(app.sawyer1.currentJoints,'workspace',app.sawyer1.workspace,'floorlevel', 0);
            pause(0.001);
        end

        % Value changed function: q3Spinner
        function q3SpinnerValueChanged(app, event)
            value = app.q3Spinner.Value;
            app.sawyer1.currentJoints(4) = deg2rad(value);
            app.sawyer1.model.plot3d(app.sawyer1.currentJoints,'workspace',app.sawyer1.workspace,'floorlevel', 0);
            pause(0.001);
        end

        % Value changed function: q4Spinner
        function q4SpinnerValueChanged(app, event)
            value = app.q4Spinner.Value;
            app.sawyer1.currentJoints(5) = deg2rad(value);
            app.sawyer1.model.plot3d(app.sawyer1.currentJoints,'workspace',app.sawyer1.workspace,'floorlevel', 0);
            pause(0.001);
        end

        % Value changed function: q5Spinner
        function q5SpinnerValueChanged(app, event)
            value = app.q5Spinner.Value;
            app.sawyer1.currentJoints(6) = deg2rad(value);
            app.sawyer1.model.plot3d(app.sawyer1.currentJoints,'workspace',app.sawyer1.workspace,'floorlevel', 0);
            pause(0.001);
        end

        % Value changed function: q6Spinner
        function q6SpinnerValueChanged(app, event)
            value = app.q6Spinner.Value;
            app.sawyer1.currentJoints(7) = deg2rad(value);
            app.sawyer1.model.plot3d(app.sawyer1.currentJoints,'workspace',app.sawyer1.workspace,'floorlevel', 0);
            pause(0.001);
        end

        % Value changed function: XSpinner
        function XSpinnerValueChanged(app, event)
            value = app.XSpinner.Value;
        end

        % Value changed function: YSpinner
        function YSpinnerValueChanged(app, event)
            value = app.YSpinner.Value;
        end

        % Value changed function: ZSpinner
        function ZSpinnerValueChanged(app, event)
            value = app.ZSpinner.Value;
        end

        % Button pushed function: MoveItButton
        function MoveItButtonPushed(app, event)
            targetPose = transl(app.XSpinner.Value, app.YSpinner.Value, app.ZSpinner.Value);
            app.motion.rmrcToPointFromCurrent(app.sawyer1, targetPose);
        end

        % Button pushed function: EStopButton
        function EStopButtonPushed(app, event)
            %error('E-Stop Activated. Program terminated.');
            %quit("force");
            disp('E-stop pressed');
            pause;
        end

        % Button pushed function: BowlButton
        function BowlButtonPushed(app, event)
            app.bowl = body(app.workSpace, 'bowlimproved', app.bowlBaseCoord, app.floorOffset);
        end

        % Button pushed function: MushroomButton
        function MushroomButtonPushed(app, event)
            app.mushroom = body(app.workSpace, 'mushroom', app.mushroomBaseCoord, app.floorOffset);
        end

        % Button pushed function: TomatoButton
        function TomatoButtonPushed(app, event)
            app.tomato = body(app.workSpace, 'tomato', app.tomatoBaseCoord, app.floorOffset);
        end

        % Button pushed function: CarrotButton
        function CarrotButtonPushed(app, event)
            app.carrot = body(app.workSpace, 'carrot', app.carrotBaseCoord, app.floorOffset);
        end

        % Button pushed function: LettuceButton
        function LettuceButtonPushed(app, event)
            app.lettuce = body(app.workSpace, 'lettuce', app.lettuceBaseCoord, app.floorOffset);
        end

        % Button pushed function: OnionButton
        function OnionButtonPushed(app, event)
            app.onion = body(app.workSpace, 'onion', app.onionBaseCoord, app.floorOffset);
        end

        % Button pushed function: TomatoSauceButton
        function TomatoSauceButtonPushed(app, event)
            app.tomatoSauce = body(app.workSpace, 'tomatosauce', app.tomatoSauceBaseCoord, app.floorOffset);
        end

        % Button pushed function: MakeMySaladButton
        function MakeMySaladButtonPushed(app, event)
            app.motion.rmrcToPointFromCurrent(app.sawyer1, app.knifeCoord);
            app.motion.rmrcToPointFromCurrentWBodies(app.sawyer1, app.sawywerInit, [app.knife]);
            
            % Cutting Mushroom
            app.motion.cutVeg(app.sawyer1, [app.knife], app.mushroomBaseCoord, transl(-0.1, 0.3, 0.27) * trotz(deg2rad(90)), transl(-0.1, 0.3, 0.35) * trotz(deg2rad(90)), transl(-0.1, 0.2, 0.35) * trotz(deg2rad(90)), transl(-0.1, 0.3, 0.27) * trotz(deg2rad(90)));
            
            % Cutting Tomato
            app.motion.rmrcToPointFromCurrentWBodies(app.sawyer1, app.tomatoBaseCoord * transl(0,0,0.4), [app.knife]);
            app.motion.cutVeg(app.sawyer1, [app.knife], app.tomatoBaseCoord,   transl(-0.1, 0.3, 0.27) * trotz(deg2rad(90)), transl(-0.1, 0.3, 0.35) * trotz(deg2rad(90)), transl(-0.1, 0.05, 0.35) * trotz(deg2rad(90)), transl(-0.1, 0.3, 0.27) * trotz(deg2rad(90)));
            
            % Cutting Carrot
            app.motion.rmrcToPointFromCurrentWBodies(app.sawyer1, app.carrotBaseCoord * transl(0,0.1,0.4) * trotz(deg2rad(90)), [app.knife]);
            app.motion.cutVeg(app.sawyer1, [app.knife], app.carrotBaseCoord,   transl(-0.2, 0.5, 0.27) * trotz(deg2rad(145)), transl(-0.1, 0.3, 0.35) * trotz(deg2rad(90)), transl(0, 0.5, 0.27) * trotz(deg2rad(90)), transl(0, 0.3, 0.27) * trotz(deg2rad(90)));
            
            % Put knife down
            app.motion.rmrcToPointFromCurrentWBodies(app.sawyer1, app.sawywerInit, [app.knife]);
            app.motion.rmrcToPointFromCurrentWBodies(app.sawyer1, transl(0,-0.4, 0.26),  [app.knife]);
            app.motion.rmrcToPointFromCurrent(app.sawyer1, app.sawywerInit);
            
            % Put things in bowl
            app.motion.rmrcToPointFromCurrent(app.sawyer1, app.mushroomBaseCoord);
            app.motion.rmrcToPointFromCurrentWBodies(app.sawyer1, app.sawywerInit,    [app.mushroom]);
            app.motion.rmrcToPointFromCurrentWBodies(app.sawyer1, app.bowlBaseCoord,  [app.mushroom]);
            
            app.motion.rmrcToPointFromCurrent(app.sawyer1, app.sawywerInit);
            app.motion.rmrcToPointFromCurrent(app.sawyer1, app.tomatoBaseCoord);
            app.motion.rmrcToPointFromCurrentWBodies(app.sawyer1, app.sawywerInit,    [app.tomato]);
            app.motion.rmrcToPointFromCurrentWBodies(app.sawyer1, app.bowlBaseCoord,  [app.tomato]);
            
            app.motion.rmrcToPointFromCurrent(app.sawyer1, app.sawywerInit);
            app.motion.rmrcToPointFromCurrent(app.sawyer1, app.carrotBaseCoord);
            app.motion.rmrcToPointFromCurrentWBodies(app.sawyer1, app.sawywerInit,    [app.carrot]);
            app.motion.rmrcToPointFromCurrentWBodies(app.sawyer1, app.bowlBaseCoord,  [app.carrot]);
            
            app.motion.rmrcToPointFromCurrent(app.sawyer1, app.sawywerInit);
            app.motion.rmrcToPointFromCurrent(app.sawyer1, app.onionBaseCoord);
            app.motion.rmrcToPointFromCurrentWBodies(app.sawyer1, app.sawywerInit,    [app.lettuce]);
            app.motion.rmrcToPointFromCurrentWBodies(app.sawyer1, app.bowlBaseCoord,  [app.lettuce]);
            
            app.motion.rmrcToPointFromCurrent(app.sawyer1, app.sawywerInit);
            app.motion.rmrcToPointFromCurrent(app.sawyer1, app.lettuceBaseCoord);
            app.motion.rmrcToPointFromCurrentWBodies(app.sawyer1, app.sawywerInit,    [app.onion]);
            app.motion.rmrcToPointFromCurrentWBodies(app.sawyer1, app.bowlBaseCoord,  [app.onion]);
            
            app.motion.rmrcToPointFromCurrent(app.sawyer1, app.sawywerInit);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.q0SpinnerLabel = uilabel(app.UIFigure);
            app.q0SpinnerLabel.Text = 'q0';
            app.q0Spinner = uispinner(app.UIFigure);
            app.q0Spinner.ValueChangedFcn = createCallbackFcn(app, @q0SpinnerValueChanged, true);
            app.q1SpinnerLabel = uilabel(app.UIFigure);
            app.q1SpinnerLabel.Text = 'q1';
            app.q1Spinner = uispinner(app.UIFigure);
            app.q1Spinner.ValueChangedFcn = createCallbackFcn(app, @q1SpinnerValueChanged, true);
            app.q2SpinnerLabel = uilabel(app.UIFigure);
            app.q2SpinnerLabel.Text = 'q2';
            app.q2Spinner = uispinner(app.UIFigure);
            app.q2Spinner.ValueChangedFcn = createCallbackFcn(app, @q2SpinnerValueChanged, true);
            app.q3SpinnerLabel = uilabel(app.UIFigure);
            app.q3SpinnerLabel.Text = 'q3';
            app.q3Spinner = uispinner(app.UIFigure);
            app.q3Spinner.ValueChangedFcn = createCallbackFcn(app, @q3SpinnerValueChanged, true);
            app.q4SpinnerLabel = uilabel(app.UIFigure);
            app.q4SpinnerLabel.Text = 'q4';
            app.q4Spinner = uispinner(app.UIFigure);
            app.q4Spinner.ValueChangedFcn = createCallbackFcn(app, @q4SpinnerValueChanged, true);
            app.q5SpinnerLabel = uilabel(app.UIFigure);
            app.q5SpinnerLabel.Text = 'q5';
            app.q5Spinner = uispinner(app.UIFigure);
            app.q5Spinner.ValueChangedFcn = createCallbackFcn(app, @q5SpinnerValueChanged, true);
            app.q6SpinnerLabel = uilabel(app.UIFigure);
            app.q6SpinnerLabel.Text = 'q6';
            app.q6Spinner = uispinner(app.UIFigure);
            app.q6Spinner.ValueChangedFcn = createCallbackFcn(app, @q6SpinnerValueChanged, true);
            app.CommittoOrderButton = uibutton(app.UIFigure, 'push');
            app.CommittoOrderButton.ButtonPushedFcn = createCallbackFcn(app, @CommittoOrderButtonPushed, true);
            app.CommittoOrderButton.Text = 'Commit to Order';
            app.TeachmodeoveridesjointanglesLabel = uilabel(app.UIFigure);
            app.TeachmodeoveridesjointanglesLabel.Text = 'Teach mode (overides joint angles)';
            app.XSpinnerLabel = uilabel(app.UIFigure);
            app.XSpinnerLabel.Text = 'X';
            app.XSpinner = uispinner(app.UIFigure);
            app.XSpinner.ValueChangedFcn = createCallbackFcn(app, @XSpinnerValueChanged, true);
            app.YSpinnerLabel = uilabel(app.UIFigure);
            app.YSpinnerLabel.Text = 'Y';
            app.YSpinner = uispinner(app.UIFigure);
            app.YSpinner.ValueChangedFcn = createCallbackFcn(app, @YSpinnerValueChanged, true);
            app.ZSpinnerLabel = uilabel(app.UIFigure);
            app.ZSpinnerLabel.Text = 'Z';
            app.ZSpinner = uispinner(app.UIFigure);
            app.ZSpinner.ValueChangedFcn = createCallbackFcn(app, @ZSpinnerValueChanged, true);
            app.ManualEndeffectorControlLabel = uilabel(app.UIFigure);
            app.ManualEndeffectorControlLabel.Text = 'Manual Endeffector Control';
            app.MoveItButton = uibutton(app.UIFigure, 'push');
            app.MoveItButton.ButtonPushedFcn = createCallbackFcn(app, @MoveItButtonPushed, true);
            app.MoveItButton.Text = 'Move It!';
            app.EStopButton = uibutton(app.UIFigure, 'push');
            app.EStopButton.ButtonPushedFcn = createCallbackFcn(app, @EStopButtonPushed, true);
            app.EStopButton.Text = 'E-Stop';
            app.IngredientsListLabel = uilabel(app.UIFigure);
            app.IngredientsListLabel.Text = 'Ingredients List';
            app.BowlButton = uibutton(app.UIFigure, 'push');
            app.BowlButton.ButtonPushedFcn = createCallbackFcn(app, @BowlButtonPushed, true);
            app.BowlButton.Text = 'Bowl';
            app.MushroomButton = uibutton(app.UIFigure, 'push');
            app.MushroomButton.ButtonPushedFcn = createCallbackFcn(app, @MushroomButtonPushed, true);
            app.MushroomButton.Text = 'Mushroom';
            app.TomatoButton = uibutton(app.UIFigure, 'push');
            app.TomatoButton.ButtonPushedFcn = createCallbackFcn(app, @TomatoButtonPushed, true);
            app.TomatoButton.Text = 'Tomato';
            app.CarrotButton = uibutton(app.UIFigure, 'push');
            app.CarrotButton.ButtonPushedFcn = createCallbackFcn(app, @CarrotButtonPushed, true);
            app.CarrotButton.Text = 'Carrot';
            app.LettuceButton = uibutton(app.UIFigure, 'push');
            app.LettuceButton.ButtonPushedFcn = createCallbackFcn(app, @LettuceButtonPushed, true);
            app.LettuceButton.Text = 'Lettuce';
            app.OnionButton = uibutton(app.UIFigure, 'push');
            app.OnionButton.ButtonPushedFcn = createCallbackFcn(app, @OnionButtonPushed, true);
            app.OnionButton.Text = 'Onion';
            app.TomatoSauceButton = uibutton(app.UIFigure, 'push');
            app.TomatoSauceButton.ButtonPushedFcn = createCallbackFcn(app, @TomatoSauceButtonPushed, true);
            app.TomatoSauceButton.Text = 'Tomato Sauce';
            app.MakeMySaladButton = uibutton(app.UIFigure, 'push');
            app.MakeMySaladButton.ButtonPushedFcn = createCallbackFcn(app, @MakeMySaladButtonPushed, true);
            app.MakeMySaladButton.Text = 'Make My Salad!';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end